classdef emClarityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        emC_app                       matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        TextArea                      matlab.ui.control.TextArea
        TextArea_2                    matlab.ui.control.TextArea
        OverviewTab_2                 matlab.ui.container.Tab
        TextArea_3                    matlab.ui.control.TextArea
        TextArea_4                    matlab.ui.control.TextArea
        tbl_recentProjects            matlab.ui.control.Table
        NewProjectNameEditFieldLabel  matlab.ui.control.Label
        NewProjectNameEditField       matlab.ui.control.EditField
        AssetsTab                     matlab.ui.container.Tab
        TabGroup2_5                   matlab.ui.container.TabGroup
        MoviesTab                     matlab.ui.container.Tab
        TextArea_5                    matlab.ui.control.TextArea
        TiltseriesTab                 matlab.ui.container.Tab
        TextArea_14                   matlab.ui.control.TextArea
        tbl_TiltSeries                matlab.ui.control.Table
        ImporttiltseriesButton        matlab.ui.control.Button
        PixelSizeAEditFieldLabel      matlab.ui.control.Label
        PixelSizeAEditField           matlab.ui.control.NumericEditField
        VoltagekVEditFieldLabel       matlab.ui.control.Label
        VoltagekVEditField            matlab.ui.control.NumericEditField
        CsmmEditFieldLabel            matlab.ui.control.Label
        CsmmEditField                 matlab.ui.control.NumericEditField
        AmpllitudeFractionEditFieldLabel  matlab.ui.control.Label
        AmpllitudeFractionEditField   matlab.ui.control.NumericEditField
        TargetDefocusmicronEditFieldLabel  matlab.ui.control.Label
        TargetDefocusmicronEditField  matlab.ui.control.NumericEditField
        FiducialDiameterAEditFieldLabel  matlab.ui.control.Label
        FiducialDiameterAEditField    matlab.ui.control.NumericEditField
        DeleteButton                  matlab.ui.control.Button
        ParticlePositionsTab          matlab.ui.container.Tab
        TextArea_7                    matlab.ui.control.TextArea
        DVolumesTab                   matlab.ui.container.Tab
        TextArea_8                    matlab.ui.control.TextArea
        EtcTab_2                      matlab.ui.container.Tab
        ActionsTab                    matlab.ui.container.Tab
        TabGroup2_2                   matlab.ui.container.TabGroup
        AlignMoviesTab                matlab.ui.container.Tab
        TextArea_13                   matlab.ui.control.TextArea
        AlignTiltseriesTab            matlab.ui.container.Tab
        TextArea_12                   matlab.ui.control.TextArea
        FindCTFTab                    matlab.ui.container.Tab
        EditField2                    matlab.ui.control.NumericEditField
        FindParticlesTab              matlab.ui.container.Tab
        TextArea_10                   matlab.ui.control.TextArea
        DvolumesTab                   matlab.ui.container.Tab
        TextArea_9                    matlab.ui.control.TextArea
        ResultsTab                    matlab.ui.container.Tab
        TabGroup2_4                   matlab.ui.container.TabGroup
        AlignMoviesTab_2              matlab.ui.container.Tab
        AlignTiltseriesTab_2          matlab.ui.container.Tab
        FindCTFTab_2                  matlab.ui.container.Tab
        FindParticlesTab_2            matlab.ui.container.Tab
        ResEtcTab                     matlab.ui.container.Tab
        SettingsTab                   matlab.ui.container.Tab
        ProjectNameEditFieldLabel     matlab.ui.control.Label
        ProjectNameEditField          matlab.ui.control.EditField
        nGPUsEditFieldLabel           matlab.ui.control.Label
        nGPUsEditField                matlab.ui.control.NumericEditField
        nCpuCoresEditFieldLabel       matlab.ui.control.Label
        nCpuCoresEditField            matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        
        % Fixed parameters
        emC_CACHE = '~/.emClarityCache';
        
        % For tilt-series
        nTiltSeries = 0 % Description
        nTiltSeriesAdded = 0;
        dTiltSeries_idx = [0,0];
        
        % For project metaData
        recentProjectFile;
        emC; % Datastructure


    end
    
    properties (Access = protected)
        
    end
    
    properties (Access = public)
      
        dTiltSeries = { 'TiltSeries name', 1.0, 3838, 3710, 41, 1.35, 300, 2.7, 1.25, 0.10, 10  }
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tbl_TiltSeries.Data = app.dTiltSeries;
            app.recentProjectFile = sprintf('%s/recentProjects.mat',app.emC_CACHE);
            app.recentProjectFile
            
            if isfile(app.recentProjectFile)   
                load(app.recentProjectFile,'recentProjects');
                app.tbl_recentProjects.Data = recentProjects;
            else
                system(sprintf('mkdir -p %s',app.emC_CACHE));
                app.tbl_recentProjects.Data = {'No recent projects!'};
            end
        end

        % Button pushed function: ImporttiltseriesButton
        function ImporttiltseriesButtonPushed(app, event)
            % TODO add a check on duplicate files
            [ fileName, pathName ] = uigetfile('*.mrc; *.st; *.fixed','MultiSelect','on');

            fprintf('There are currently %d tilt-series\n',app.nTiltSeries);cd 
            
            if ~iscell(fileName)
                fileName = {fileName};
            end
   
            for iFile = 1:length(fileName)
                fprintf('\n%s %s\n',fileName{iFile}, pathName);
                app.nTiltSeries = app.nTiltSeries + 1;
                app.nTiltSeriesAdded = app.nTiltSeriesAdded + 1;
                app.dTiltSeries{ app.nTiltSeries, 1 } =  fileName{iFile};
                app.dTiltSeries{ app.nTiltSeries, 2 } =  app.nTiltSeriesAdded;
                % Pull in info from the header
                iHeader = getHeader(MRCImage(fullfile(pathName,fileName{iFile})));
                app.dTiltSeries{ app.nTiltSeries, 3 } = iHeader.nX;
                app.dTiltSeries{ app.nTiltSeries, 4 } = iHeader.nY;
                app.dTiltSeries{ app.nTiltSeries, 5 } = iHeader.nZ;
                app.dTiltSeries{ app.nTiltSeries, 6 } = app.PixelSizeAEditField.Value;
                app.dTiltSeries{ app.nTiltSeries, 7 } = app.VoltagekVEditField.Value;
                app.dTiltSeries{ app.nTiltSeries, 8 } = app.CsmmEditField.Value;
                app.dTiltSeries{ app.nTiltSeries, 9 } = app.AmpllitudeFractionEditField.Value;
                app.dTiltSeries{ app.nTiltSeries, 10 } = app.TargetDefocusmicronEditField.Value;
                app.dTiltSeries{ app.nTiltSeries, 11 } = app.FiducialDiameterAEditField.Value;
                % Thow warning if pixel size doesn't matchheader
                
            end   
            
            fprintf('There are currently %d tilt-series\n',app.nTiltSeries);
            
            % Update the table
            app.tbl_TiltSeries.Data = app.dTiltSeries;
            
        end

        % Value changed function: NewProjectNameEditField
        function AddNewProjectName(app, event)
            value = app.NewProjectNameEditField.Value;
            
            % Check that the project is unique
            title = 'Confirm New Project';
            if isfile(sprintf('%s.mat',value))
                msg = sprintf('A project of this name (%s) already exists, overwrite?',value);
                opts = {'Overwrite','Re-enter a new name'};
            else
                msg = sprintf('Create a new refinement project with the name %s?.',value);
                opts = {'Create New', 'Cancel'};
            end
            
            f = uifigure;        
            selection = uiconfirm(f,msg,title,...
           'Options',opts);
            
            %%% TODO add a method to update the recent projects list
            switch selection
                case 'Create New'
                    app.emC = struct();
                    subTomoMeta = app.emC;
                    save(sprintf('%s.mat',value),'subTomoMeta');
                    tmpPrjName = sprintf('%s/%s.mat',pwd, value);
                    if isfile(app.recentProjectFile)
                        
                        load(app.recentProjectFile,'recentProjects');
           
                        tmpProjects = cell(length(recentProjects)+1,1);
                        tmpProjects{1} = tmpPrjName;
                        tmpProjects{1}
                        for iPrj = 1:length(recentProjects)
                            tmpProjects{iPrj+1} = recentProjects{iPrj};
                            tmpProjects{iPrj+1}
                        end
                        recentProjects = tmpProjects;
                        clear tmpProjects tmpPrjName
                    else
                        recentProjects = {tmpPrjName};
                    end
                    
                    save(app.recentProjectFile,'recentProjects','-mat');
                    
                case 'Overwrite'
                    system(sprintf('mv %s.mat %s_bckup.mat',value, value));
                    app.emC = struct();
                    subTomoMeta = app.emC;
                    save(sprintf('%s.mat',value),'subTomoMeta');

                otherwise
                % Nothing happens, just go back to the main
            end
            
            delete(f);

        end

        % Cell selection callback: tbl_TiltSeries
        function tbl_TiltSeriesCellSelection(app, event)
            app.DeleteButton.Enable = 1;
            app.dTiltSeries_idx = event.Indices;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            % What is the best was to do this?
            rowIDX = app.dTiltSeries_idx(:,1);
            newTable = cell(app.nTiltSeries - length(rowIDX),11);
            nRow = 1;
            for iRow = 1:app.nTiltSeries
               if ~ismember(iRow,rowIDX)
                   for iCol = 1:11
                    newTable{nRow,iCol} = app.dTiltSeries{iRow,iCol};
                   end
                   nRow = nRow + 1;
               end
            end
            
            app.dTiltSeries = newTable;
            clear newTable
            app.nTiltSeries = app.nTiltSeries - length(rowIDX);
            app.DeleteButton.Enable = 0;
            % Update the table
            app.tbl_TiltSeries.Data = app.dTiltSeries;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.emC_app = uifigure('Visible', 'off');
            app.emC_app.Name = 'emClarity';
            app.TabGroup = uitabgroup(app.emC_app);
            app.Tab = uitab(app.TabGroup);
            app.TextArea = uitextarea(app.Tab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'enhanced macromolecular Classification and Alignment '; 'for '; 'high-resolution in situ tomography'};
            app.TextArea_2 = uitextarea(app.Tab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'emClarity'};
            app.OverviewTab_2 = uitab(app.TabGroup);
            app.OverviewTab_2.Title = 'Overview';
            app.TextArea_3 = uitextarea(app.OverviewTab_2);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'enhanced macromolecular Classification and Alignment '; 'for '; 'high-resolution in situ tomography'};
            app.TextArea_4 = uitextarea(app.OverviewTab_2);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'emClarity'};
            app.tbl_recentProjects = uitable(app.OverviewTab_2);
            app.tbl_recentProjects.ColumnName = {'Recent Projects'};
            app.tbl_recentProjects.RowName = {};
            app.NewProjectNameEditFieldLabel = uilabel(app.OverviewTab_2);
            app.NewProjectNameEditFieldLabel.Text = 'New Project Name';
            app.NewProjectNameEditField = uieditfield(app.OverviewTab_2, 'text');
            app.NewProjectNameEditField.ValueChangedFcn = createCallbackFcn(app, @AddNewProjectName, true);
            app.AssetsTab = uitab(app.TabGroup);
            app.AssetsTab.Title = 'Assets';
            app.TabGroup2_5 = uitabgroup(app.AssetsTab);
            app.MoviesTab = uitab(app.TabGroup2_5);
            app.MoviesTab.Title = 'Movies';
            app.TextArea_5 = uitextarea(app.MoviesTab);
            app.TextArea_5.Value = {'Movie alignments are expected to be performed ahead of entering the emClarity workflow. For now, there are just too many crazy naming, numbering schemes to invest the time in getting this to work.'; ''; 'I recommend using unblur (CITE) and there is an example script that I use for my own work provided in the emClarity/docs/scripts folder.'};
            app.TiltseriesTab = uitab(app.TabGroup2_5);
            app.TiltseriesTab.Title = ' Tilt-series';
            app.TextArea_14 = uitextarea(app.TiltseriesTab);
            app.TextArea_14.Editable = 'off';
            app.TextArea_14.Value = {'Imaging paramters'};
            app.tbl_TiltSeries = uitable(app.TiltseriesTab);
            app.tbl_TiltSeries.ColumnName = {'data Name'; 'data IDX'; 'nX'; 'nY'; 'nZ'; 'pixelSize'; 'kV'; 'Cs'; 'target defocus'; 'Amp Ratio'; 'fiducial diameter'};
            app.tbl_TiltSeries.RowName = {};
            app.tbl_TiltSeries.ColumnSortable = [true true false false true true false false false false];
            app.tbl_TiltSeries.ColumnEditable = [false false false false false false false false false false false];
            app.tbl_TiltSeries.CellSelectionCallback = createCallbackFcn(app, @tbl_TiltSeriesCellSelection, true);
            app.ImporttiltseriesButton = uibutton(app.TiltseriesTab, 'push');
            app.ImporttiltseriesButton.ButtonPushedFcn = createCallbackFcn(app, @ImporttiltseriesButtonPushed, true);
            app.ImporttiltseriesButton.Text = 'Import tilt-series';
            app.PixelSizeAEditFieldLabel = uilabel(app.TiltseriesTab);
            app.PixelSizeAEditFieldLabel.Text = 'Pixel Size ( A )';
            app.PixelSizeAEditField = uieditfield(app.TiltseriesTab, 'numeric');
            app.PixelSizeAEditField.Value = 1.35;
            app.VoltagekVEditFieldLabel = uilabel(app.TiltseriesTab);
            app.VoltagekVEditFieldLabel.Text = 'Voltage ( kV )';
            app.VoltagekVEditField = uieditfield(app.TiltseriesTab, 'numeric');
            app.VoltagekVEditField.Value = 300;
            app.CsmmEditFieldLabel = uilabel(app.TiltseriesTab);
            app.CsmmEditFieldLabel.Text = 'Cs ( mm )';
            app.CsmmEditField = uieditfield(app.TiltseriesTab, 'numeric');
            app.CsmmEditField.Value = 2.7;
            app.AmpllitudeFractionEditFieldLabel = uilabel(app.TiltseriesTab);
            app.AmpllitudeFractionEditFieldLabel.Text = 'Ampllitude Fraction';
            app.AmpllitudeFractionEditField = uieditfield(app.TiltseriesTab, 'numeric');
            app.AmpllitudeFractionEditField.Value = 0.1;
            app.TargetDefocusmicronEditFieldLabel = uilabel(app.TiltseriesTab);
            app.TargetDefocusmicronEditFieldLabel.Text = 'Target Defocus ( micron  )';
            app.TargetDefocusmicronEditField = uieditfield(app.TiltseriesTab, 'numeric');
            app.FiducialDiameterAEditFieldLabel = uilabel(app.TiltseriesTab);
            app.FiducialDiameterAEditFieldLabel.Text = 'Fiducial Diameter ( A )';
            app.FiducialDiameterAEditField = uieditfield(app.TiltseriesTab, 'numeric');
            app.FiducialDiameterAEditField.Value = 100;
            app.DeleteButton = uibutton(app.TiltseriesTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.ParticlePositionsTab = uitab(app.TabGroup2_5);
            app.ParticlePositionsTab.Title = 'Particle Positions';
            app.TextArea_7 = uitextarea(app.ParticlePositionsTab);
            app.TextArea_7.Value = {'This will be for import/ export to other packages'};
            app.DVolumesTab = uitab(app.TabGroup2_5);
            app.DVolumesTab.Title = '3D Volumes';
            app.TextArea_8 = uitextarea(app.DVolumesTab);
            app.TextArea_8.Value = {'Use this both for importing a reference and defining it.'; ''; ''};
            app.EtcTab_2 = uitab(app.TabGroup2_5);
            app.EtcTab_2.Title = 'Etc';
            app.ActionsTab = uitab(app.TabGroup);
            app.ActionsTab.Title = 'Actions';
            app.TabGroup2_2 = uitabgroup(app.ActionsTab);
            app.AlignMoviesTab = uitab(app.TabGroup2_2);
            app.AlignMoviesTab.Title = 'Align Movies';
            app.TextArea_13 = uitextarea(app.AlignMoviesTab);
            app.TextArea_13.Editable = 'off';
            app.TextArea_13.Value = {'Movie alignments are expected to be performed ahead of entering the emClarity workflow. For now, there are just too many crazy naming, numbering schemes to invest the time in getting this to work.'; ''; 'I recommend using unblur (CITE) and there is an example script that I use for my own work provided in the emClarity/docs/scripts folder.'};
            app.AlignTiltseriesTab = uitab(app.TabGroup2_2);
            app.AlignTiltseriesTab.Title = 'Align Tilt-series';
            app.TextArea_12 = uitextarea(app.AlignTiltseriesTab);
            app.TextArea_12.Value = {'TODO, use this area to set up the project dir?'; ''; 'Could optionally call etomo or the runAuto script I''ve written. This is a bit weird to have listed prior to the action.'};
            app.FindCTFTab = uitab(app.TabGroup2_2);
            app.FindCTFTab.Title = 'Find CTF';
            app.EditField2 = uieditfield(app.FindCTFTab, 'numeric');
            app.FindParticlesTab = uitab(app.TabGroup2_2);
            app.FindParticlesTab.Title = 'Find Particles';
            app.TextArea_10 = uitextarea(app.FindParticlesTab);
            app.TextArea_10.Value = {'Text box'};
            app.DvolumesTab = uitab(app.TabGroup2_2);
            app.DvolumesTab.Title = '3D volumes';
            app.TextArea_9 = uitextarea(app.DvolumesTab);
            app.TextArea_9.Value = {'Want to be able to reScale'; ''; 'Bandpass'; ''; 'Combine'; ''; 'Mask'};
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.TabGroup2_4 = uitabgroup(app.ResultsTab);
            app.AlignMoviesTab_2 = uitab(app.TabGroup2_4);
            app.AlignMoviesTab_2.Title = 'Align Movies';
            app.AlignTiltseriesTab_2 = uitab(app.TabGroup2_4);
            app.AlignTiltseriesTab_2.Title = 'Align Tilt-series';
            app.FindCTFTab_2 = uitab(app.TabGroup2_4);
            app.FindCTFTab_2.Title = 'Find CTF';
            app.FindParticlesTab_2 = uitab(app.TabGroup2_4);
            app.FindParticlesTab_2.Title = 'Find Particles';
            app.ResEtcTab = uitab(app.TabGroup2_4);
            app.ResEtcTab.Title = 'ResEtc';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ProjectNameEditFieldLabel = uilabel(app.SettingsTab);
            app.ProjectNameEditFieldLabel.Text = 'Project Name';
            app.ProjectNameEditField = uieditfield(app.SettingsTab, 'text');
            app.ProjectNameEditField.Value = 'emClarity_tutorial';
            app.nGPUsEditFieldLabel = uilabel(app.SettingsTab);
            app.nGPUsEditFieldLabel.Text = 'nGPUs';
            app.nGPUsEditField = uieditfield(app.SettingsTab, 'numeric');
            app.nGPUsEditField.Value = 2;
            app.nCpuCoresEditFieldLabel = uilabel(app.SettingsTab);
            app.nCpuCoresEditFieldLabel.Text = 'nCpuCores';
            app.nCpuCoresEditField = uieditfield(app.SettingsTab, 'numeric');
            app.nCpuCoresEditField.Value = 4;
            % Show the figure after all components are created
            app.emC_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = emClarityApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.emC_app)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.emC_app)
        end
    end
end